classdef monster < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MONSTeRUIFigure                 matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        HomeTab                         matlab.ui.container.Tab
        MONSTeRMobileNetworkSimulatorgithubcomSonohimonsterLabel  matlab.ui.control.Label
        SetupTab                        matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        RuntimeTab                      matlab.ui.container.Tab
        Label                           matlab.ui.control.Label
        RuntimesetupLabel               matlab.ui.control.Label
        Simulationrounds1roundis1msEditFieldLabel  matlab.ui.control.Label
        Simulationrounds1roundis1msEditField  matlab.ui.control.NumericEditField
        SimulationseedEditFieldLabel    matlab.ui.control.Label
        SimulationseedEditField         matlab.ui.control.NumericEditField
        NetworkTab                      matlab.ui.container.Tab
        Label_4                         matlab.ui.control.Label
        NetworksetupLabel               matlab.ui.control.Label
        MacroeNodeBsitesEditFieldLabel  matlab.ui.control.Label
        MacroeNodeBsitesEditField       matlab.ui.control.NumericEditField
        MacrocellspersiteEditFieldLabel  matlab.ui.control.Label
        MacrocellspersiteEditField      matlab.ui.control.NumericEditField
        MacroeNodeBheightmEditFieldLabel  matlab.ui.control.Label
        MacroeNodeBheightmEditField     matlab.ui.control.NumericEditField
        MicroeNodeBsitesEditFieldLabel  matlab.ui.control.Label
        MicroeNodeBsitesEditField       matlab.ui.control.NumericEditField
        MicrocellspersiteEditFieldLabel  matlab.ui.control.Label
        MicrocellspersiteEditField      matlab.ui.control.NumericEditField
        MicroeNodeBheightmEditFieldLabel  matlab.ui.control.Label
        MicroeNodeBheightmEditField     matlab.ui.control.NumericEditField
        NumberofUEsEditFieldLabel       matlab.ui.control.Label
        NumberofUEsEditField            matlab.ui.control.NumericEditField
        UEheightmEditFieldLabel         matlab.ui.control.Label
        UEheightmEditField              matlab.ui.control.NumericEditField
        BackhaulactiveSwitchLabel       matlab.ui.control.Label
        BackhaulactiveSwitch            matlab.ui.control.Switch
        BackhaullengthmEditFieldLabel   matlab.ui.control.Label
        BackhaullengthmEditField        matlab.ui.control.NumericEditField
        MacroeNodeBmaxTXpowerWEditFieldLabel  matlab.ui.control.Label
        MacroeNodeBmaxTXpowerWEditField  matlab.ui.control.NumericEditField
        MicroeNodeBmaxTXpowerWEditFieldLabel  matlab.ui.control.Label
        MicroeNodeBmaxTXpowerWEditField  matlab.ui.control.NumericEditField
        DownlinkfrequencyMHzEditFieldLabel  matlab.ui.control.Label
        DownlinkfrequencyMHzEditField   matlab.ui.control.NumericEditField
        UplinkfrequencyMHzEditFieldLabel  matlab.ui.control.Label
        UplinkfrequencyMHzEditField     matlab.ui.control.NumericEditField
        ExportTab                       matlab.ui.container.Tab
        ExportconfigurationButton       matlab.ui.control.Button
        Label_5                         matlab.ui.control.Label
        ExportLabel                     matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        RunTab                          matlab.ui.container.Tab
        Label_2                         matlab.ui.control.Label
        RunasimulationLabel             matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        LoadsetupButton_2               matlab.ui.control.Button
        LoadthesetuptorunButton         matlab.ui.control.Button
        RunsimulationtosaveresultsButton  matlab.ui.control.Button
        SimulationexecutionlogListBoxLabel  matlab.ui.control.Label
        SimulationexecutionlogListBox   matlab.ui.control.ListBox
    end

 
 properties (Access = private)
  simulationRounds % number of simulation rounds
  seed
  macroEnbSites
  macroEnbCells
  macroEnbHeight
  macroEnbPower
  microEnbSites
  microEnbCells
  microEnbHeight
  microEnbPower
  dlFreq
  ulFreq
  ueNumber
  ueHeight
  backhaulOn
  backhaulLength
  Config
  Logger
  Simulation;
  dateFormat = 'yyyy-mm-dd_HH.MM.SS';
 end
 
 methods (Access = private)
  
  function app = updateAppLog(app)
   logStore = app.Simulation.Logger.logStore;
   app.SimulationexecutionlogListBox.Items = logStore.messages;
   scroll(app.SimulationexecutionlogListBox, logStore.messages(logStore.iLogLatest));
   % Call a short pause to allow rendering
   pause(0.00000001);
   drawnow;
  end
  
  function Config = generateConfig(app)
   Config = MonsterConfig();
   if ~isempty(app.simulationRounds)
    Config.Runtime.simulationRounds = app.simulationRounds;
   end
   if ~isempty(app.macroEnbSites)
    Config.MacroEnb.sitesNumber = app.macroEnbSites;
   end
   if ~isempty(app.macroEnbCells)
    Config.MacroEnb.cellsPerSite = app.macroEnbCells;
   end
   if ~isempty(app.macroEnbHeight)
    Config.MacroEnb.height = app.macroEnbHeight;
   end
   if ~isempty(app.macroEnbPower)
    Config.MacroEnb.Pmax = app.macroEnbPower;
   end
   
   if ~isempty(app.microEnbSites)
    Config.MicroEnb.sitesNumber = app.microEnbSites;
   end
   if ~isempty(app.microEnbCells)
    Config.MicroEnb.cellsPerSite = app.microEnbCells;
   end
   if ~isempty(app.microEnbHeight)
    Config.MicroEnb.height = app.microEnbHeight;
   end
   if ~isempty(app.microEnbPower)
    Config.MicroEnb.Pmax = app.microEnbPower;
   end
   
   if ~isempty(app.ueNumber)
    Config.Ue.number = app.ueNumber;
   end
   if ~isempty(app.ueHeight)
    Config.Ue.height = app.ueHeight;
   end
   
   if ~isempty(app.dlFreq)
    Config.Phy.downlinkFrequency = app.dlFreq;
   end
   if ~isempty(app.ulFreq)
    Config.Phy.uplinkFrequency = app.ulFreq;
   end
   
   
   if ~isempty(app.backhaulOn)
    Config.Backhaul.backhaulOn = app.backhaulOn;
   end
   if ~isempty(app.backhaulLength)
    Config.Backhaul.lengthOfMedium = app.backhaulLength;
   end
   
  end
 end
 

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
   
        end

        % Value changed function: Simulationrounds1roundis1msEditField
        function Simulationrounds1roundis1msEditFieldValueChanged(app, event)
   value = app.Simulationrounds1roundis1msEditField.Value;
   app.simulationRounds = value;
        end

        % Button pushed function: LoadsetupButton_2
        function LoadsetupButton_2Pushed(app, event)
   set(app.LoadsetupButton_2, 'Text', 'Loading setup...')
   set(app.LoadsetupButton_2, 'Enable', 'off')
   app.Config = app.generateConfig();
   app.Config.Runtime.mode = 'app';
   app.Config.SimulationPlot.runtimePlot = 0;
   % Pause
   pause(0.5);
   set(app.LoadsetupButton_2, 'Text', 'Setup loaded')
   set(app.LoadthesetuptorunButton, 'Enable', 'on')
   set(app.LoadthesetuptorunButton, 'Text', 'Run')
        end

        % Button pushed function: LoadthesetuptorunButton
        function LoadthesetuptorunButtonPushed(app, event)
   set(app.LoadthesetuptorunButton, 'Enable', 'off');
   set(app.LoadthesetuptorunButton, 'Text', 'Simulation running');
   app.SimulationexecutionlogListBox.Items = ["Simulation initialising..."];
   % Pause
   pause(0.05);
   app.Logger = MonsterLog(app.Config);
   app.Simulation = Monster(app.Config, app.Logger);
   app.Simulation.Logger.log('(MAIN) simulation instance created, starting...', 'NFO');
   app.updateAppLog();
   for iRound = 0:(app.Simulation.Runtime.totalRounds - 1)
   	app.Simulation.setupRound(iRound);
    app.updateAppLog();
   	app.Simulation.Logger.log(sprintf('(MAIN) simulation round %i, time elapsed %f s, time left %f s',...
   		app.Simulation.Runtime.currentRound, app.Simulation.Runtime.currentTime, ...
   		app.Simulation.Runtime.remainingTime ), 'NFO');
   	app.updateAppLog();
   	app.Simulation.run();
    app.updateAppLog();
   	app.Simulation.Logger.log(sprintf('(MAIN) completed simulation round %i. %i rounds left' ,....
   		app.Simulation.Runtime.currentRound, app.Simulation.Runtime.remainingRounds), 'NFO');
    app.updateAppLog();
   	app.Simulation.collectResults();
    app.updateAppLog();
   	app.Simulation.Logger.log('(MAIN) collected simulation round results', 'NFO');
    app.updateAppLog();
   	app.Simulation.clean();
    app.updateAppLog();
   	if iRound ~= app.Simulation.Runtime.totalRounds - 1
	    app.Simulation.Logger.log('(MAIN) cleaned parameters for next round', 'NFO');
   	else
	    app.Simulation.Logger.log('(MAIN) simulation completed', 'NFO');
   	end
    app.updateAppLog();
   end
   set(app.RunsimulationtosaveresultsButton, 'Enable', 'on')
   set(app.RunsimulationtosaveresultsButton, 'Text', 'Save results')
   set(app.LoadsetupButton_2, 'Enable', 'on')
   set(app.LoadsetupButton_2, 'Text', 'Load setup')
        end

        % Button pushed function: RunsimulationtosaveresultsButton
        function RunsimulationtosaveresultsButtonPushed(app, event)
   set(app.RunsimulationtosaveresultsButton, 'Enable', 'off')
   set(app.RunsimulationtosaveresultsButton, 'Text', 'Saving results')
   Simulation = app.Simulation;
   fileName = strcat('monster_results', datestr(datetime, app.dateFormat));
   uisave('Simulation', fileName)
   set(app.RunsimulationtosaveresultsButton, 'Text', 'Run simulation to save results')
        end

        % Value changed function: MacroeNodeBsitesEditField
        function MacroeNodeBsitesEditFieldValueChanged(app, event)
   value = app.MacroeNodeBsitesEditField.Value;
   app.macroEnbSites = value;
        end

        % Value changed function: MacrocellspersiteEditField
        function MacrocellspersiteEditFieldValueChanged(app, event)
   value = app.MacrocellspersiteEditField.Value;
   app.macroEnbCells = value;
        end

        % Value changed function: MacroeNodeBheightmEditField
        function MacroeNodeBheightmEditFieldValueChanged(app, event)
   value = app.MacroeNodeBheightmEditField.Value;
   app.macroEnbHeight = value;
        end

        % Value changed function: MicroeNodeBsitesEditField
        function MicroeNodeBsitesEditFieldValueChanged(app, event)
   value = app.MicroeNodeBsitesEditField.Value;
   app.microEnbSites = value;
        end

        % Value changed function: MicrocellspersiteEditField
        function MicrocellspersiteEditFieldValueChanged(app, event)
   value = app.MicrocellspersiteEditField.Value;
   app.microEnbCells = value;
        end

        % Value changed function: MicroeNodeBheightmEditField
        function MicroeNodeBheightmEditFieldValueChanged(app, event)
   value = app.MicroeNodeBheightmEditField.Value;
   app.microEnbHeight = value;
        end

        % Value changed function: NumberofUEsEditField
        function NumberofUEsEditFieldValueChanged(app, event)
   value = app.NumberofUEsEditField.Value;
   app.ueNumber = value;
        end

        % Value changed function: UEheightmEditField
        function UEheightmEditFieldValueChanged(app, event)
   value = app.UEheightmEditField.Value;
   app.ueHeight = value;
        end

        % Value changed function: BackhaulactiveSwitch
        function BackhaulactiveSwitchValueChanged(app, event)
   value = app.BackhaulactiveSwitch.Value;
   if strcmp(value, 'on')
    app.backhaulOn = 1;
   else
    app.backhaulOn = 0;
   end
        end

        % Value changed function: BackhaullengthmEditField
        function BackhaullengthmEditFieldValueChanged(app, event)
   value = app.BackhaullengthmEditField.Value;
   app.backhaulLength = value;
        end

        % Button pushed function: ExportconfigurationButton
        function ExportconfigurationButtonPushed(app, event)
   set(app.ExportconfigurationButton, "Enable", "Off");
   set(app.ExportconfigurationButton, "Text", "Exporting configuration")
   Config = app.generateConfig();
   fileName = strcat('monster_config', datestr(datetime, app.dateFormat));
   uisave('Config', fileName)
   set(app.ExportconfigurationButton, "Text", "Export completed")
   pause(1);
   set(app.ExportconfigurationButton, "Enable", "On");
   set(app.ExportconfigurationButton, "Text", "Export configuration")
   
        end

        % Value changed function: MacroeNodeBmaxTXpowerWEditField
        function MacroeNodeBmaxTXpowerWEditFieldValueChanged(app, event)
   value = app.MacroeNodeBmaxTXpowerWEditField.Value;
   app.macroEnbPower = value;
        end

        % Value changed function: MicroeNodeBmaxTXpowerWEditField
        function MicroeNodeBmaxTXpowerWEditFieldValueChanged(app, event)
   value = app.MicroeNodeBmaxTXpowerWEditField.Value;
   app.microEnbPower = value;
        end

        % Value changed function: DownlinkfrequencyMHzEditField
        function DownlinkfrequencyMHzEditFieldValueChanged(app, event)
   value = app.DownlinkfrequencyMHzEditField.Value;
   app.dlFreq = value;
        end

        % Value changed function: UplinkfrequencyMHzEditField
        function UplinkfrequencyMHzEditFieldValueChanged(app, event)
   value = app.UplinkfrequencyMHzEditField.Value;
   app.ulFreq = value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MONSTeRUIFigure = uifigure;
            app.MONSTeRUIFigure.Name = 'MONSTeR';
            app.TabGroup = uitabgroup(app.MONSTeRUIFigure);
            app.TabGroup.Tooltip = {''};
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.MONSTeRMobileNetworkSimulatorgithubcomSonohimonsterLabel = uilabel(app.HomeTab);
            app.MONSTeRMobileNetworkSimulatorgithubcomSonohimonsterLabel.Text = 'MONSTeR (Mobile Network Simulator) github.com/Sonohi/monster ';
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.TabGroup2 = uitabgroup(app.SetupTab);
            app.RuntimeTab = uitab(app.TabGroup2);
            app.RuntimeTab.Title = 'Runtime';
            app.Label = uilabel(app.RuntimeTab);
            app.Label.Text = 'Use settings in this tab to edit the configuration for the simulation runtime, like length, etc.';
            app.RuntimesetupLabel = uilabel(app.RuntimeTab);
            app.RuntimesetupLabel.Text = 'Runtime setup';
            app.Simulationrounds1roundis1msEditFieldLabel = uilabel(app.RuntimeTab);
            app.Simulationrounds1roundis1msEditFieldLabel.Text = 'Simulation rounds (1 round is 1ms)';
            app.Simulationrounds1roundis1msEditField = uieditfield(app.RuntimeTab, 'numeric');
            app.Simulationrounds1roundis1msEditField.Limits = [1 Inf];
            app.Simulationrounds1roundis1msEditField.RoundFractionalValues = 'on';
            app.Simulationrounds1roundis1msEditField.ValueChangedFcn = createCallbackFcn(app, @Simulationrounds1roundis1msEditFieldValueChanged, true);
            app.Simulationrounds1roundis1msEditField.Value = 10;
            app.SimulationseedEditFieldLabel = uilabel(app.RuntimeTab);
            app.SimulationseedEditFieldLabel.Text = 'Simulation seed';
            app.SimulationseedEditField = uieditfield(app.RuntimeTab, 'numeric');
            app.SimulationseedEditField.Limits = [0 Inf];
            app.SimulationseedEditField.RoundFractionalValues = 'on';
            app.SimulationseedEditField.Value = 126;
            app.NetworkTab = uitab(app.TabGroup2);
            app.NetworkTab.Title = 'Network';
            app.Label_4 = uilabel(app.NetworkTab);
            app.Label_4.Text = 'Use settings in this tab to edit the configuration for the network topology';
            app.NetworksetupLabel = uilabel(app.NetworkTab);
            app.NetworksetupLabel.Text = 'Network setup';
            app.MacroeNodeBsitesEditFieldLabel = uilabel(app.NetworkTab);
            app.MacroeNodeBsitesEditFieldLabel.Text = 'Macro eNodeB sites';
            app.MacroeNodeBsitesEditField = uieditfield(app.NetworkTab, 'numeric');
            app.MacroeNodeBsitesEditField.Limits = [0 20];
            app.MacroeNodeBsitesEditField.RoundFractionalValues = 'on';
            app.MacroeNodeBsitesEditField.ValueChangedFcn = createCallbackFcn(app, @MacroeNodeBsitesEditFieldValueChanged, true);
            app.MacroeNodeBsitesEditField.Value = 1;
            app.MacrocellspersiteEditFieldLabel = uilabel(app.NetworkTab);
            app.MacrocellspersiteEditFieldLabel.Text = 'Macro cells per site';
            app.MacrocellspersiteEditField = uieditfield(app.NetworkTab, 'numeric');
            app.MacrocellspersiteEditField.Limits = [1 6];
            app.MacrocellspersiteEditField.RoundFractionalValues = 'on';
            app.MacrocellspersiteEditField.ValueChangedFcn = createCallbackFcn(app, @MacrocellspersiteEditFieldValueChanged, true);
            app.MacrocellspersiteEditField.Value = 3;
            app.MacroeNodeBheightmEditFieldLabel = uilabel(app.NetworkTab);
            app.MacroeNodeBheightmEditFieldLabel.Text = 'Macro eNodeB height (m)';
            app.MacroeNodeBheightmEditField = uieditfield(app.NetworkTab, 'numeric');
            app.MacroeNodeBheightmEditField.Limits = [1 100];
            app.MacroeNodeBheightmEditField.ValueChangedFcn = createCallbackFcn(app, @MacroeNodeBheightmEditFieldValueChanged, true);
            app.MacroeNodeBheightmEditField.Value = 35;
            app.MicroeNodeBsitesEditFieldLabel = uilabel(app.NetworkTab);
            app.MicroeNodeBsitesEditFieldLabel.Text = 'Micro eNodeB sites';
            app.MicroeNodeBsitesEditField = uieditfield(app.NetworkTab, 'numeric');
            app.MicroeNodeBsitesEditField.Limits = [0 20];
            app.MicroeNodeBsitesEditField.RoundFractionalValues = 'on';
            app.MicroeNodeBsitesEditField.ValueChangedFcn = createCallbackFcn(app, @MicroeNodeBsitesEditFieldValueChanged, true);
            app.MicroeNodeBsitesEditField.Value = 1;
            app.MicrocellspersiteEditFieldLabel = uilabel(app.NetworkTab);
            app.MicrocellspersiteEditFieldLabel.Text = 'Micro cells per site';
            app.MicrocellspersiteEditField = uieditfield(app.NetworkTab, 'numeric');
            app.MicrocellspersiteEditField.Limits = [1 6];
            app.MicrocellspersiteEditField.RoundFractionalValues = 'on';
            app.MicrocellspersiteEditField.ValueChangedFcn = createCallbackFcn(app, @MicrocellspersiteEditFieldValueChanged, true);
            app.MicrocellspersiteEditField.Value = 1;
            app.MicroeNodeBheightmEditFieldLabel = uilabel(app.NetworkTab);
            app.MicroeNodeBheightmEditFieldLabel.Text = 'Micro eNodeB height (m)';
            app.MicroeNodeBheightmEditField = uieditfield(app.NetworkTab, 'numeric');
            app.MicroeNodeBheightmEditField.Limits = [1 100];
            app.MicroeNodeBheightmEditField.ValueChangedFcn = createCallbackFcn(app, @MicroeNodeBheightmEditFieldValueChanged, true);
            app.MicroeNodeBheightmEditField.Value = 25;
            app.NumberofUEsEditFieldLabel = uilabel(app.NetworkTab);
            app.NumberofUEsEditFieldLabel.Text = 'Number of UEs';
            app.NumberofUEsEditField = uieditfield(app.NetworkTab, 'numeric');
            app.NumberofUEsEditField.Limits = [1 100];
            app.NumberofUEsEditField.RoundFractionalValues = 'on';
            app.NumberofUEsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofUEsEditFieldValueChanged, true);
            app.NumberofUEsEditField.Value = 10;
            app.UEheightmEditFieldLabel = uilabel(app.NetworkTab);
            app.UEheightmEditFieldLabel.Text = 'UE height (m)';
            app.UEheightmEditField = uieditfield(app.NetworkTab, 'numeric');
            app.UEheightmEditField.Limits = [1 3];
            app.UEheightmEditField.ValueChangedFcn = createCallbackFcn(app, @UEheightmEditFieldValueChanged, true);
            app.UEheightmEditField.Value = 1.5;
            app.BackhaulactiveSwitchLabel = uilabel(app.NetworkTab);
            app.BackhaulactiveSwitchLabel.Text = 'Backhaul active';
            app.BackhaulactiveSwitch = uiswitch(app.NetworkTab, 'slider');
            app.BackhaulactiveSwitch.ValueChangedFcn = createCallbackFcn(app, @BackhaulactiveSwitchValueChanged, true);
            app.BackhaulactiveSwitch.Value = 'On';
            app.BackhaullengthmEditFieldLabel = uilabel(app.NetworkTab);
            app.BackhaullengthmEditFieldLabel.Text = 'Backhaul length (m)';
            app.BackhaullengthmEditField = uieditfield(app.NetworkTab, 'numeric');
            app.BackhaullengthmEditField.Limits = [1 10000];
            app.BackhaullengthmEditField.ValueChangedFcn = createCallbackFcn(app, @BackhaullengthmEditFieldValueChanged, true);
            app.BackhaullengthmEditField.Value = 1000;
            app.MacroeNodeBmaxTXpowerWEditFieldLabel = uilabel(app.NetworkTab);
            app.MacroeNodeBmaxTXpowerWEditFieldLabel.Text = 'Macro eNodeB max TX power (W) ';
            app.MacroeNodeBmaxTXpowerWEditField = uieditfield(app.NetworkTab, 'numeric');
            app.MacroeNodeBmaxTXpowerWEditField.Limits = [1 100];
            app.MacroeNodeBmaxTXpowerWEditField.ValueDisplayFormat = '%.2f';
            app.MacroeNodeBmaxTXpowerWEditField.ValueChangedFcn = createCallbackFcn(app, @MacroeNodeBmaxTXpowerWEditFieldValueChanged, true);
            app.MacroeNodeBmaxTXpowerWEditField.Value = 20;
            app.MicroeNodeBmaxTXpowerWEditFieldLabel = uilabel(app.NetworkTab);
            app.MicroeNodeBmaxTXpowerWEditFieldLabel.Text = 'Micro eNodeB max TX power (W) ';
            app.MicroeNodeBmaxTXpowerWEditField = uieditfield(app.NetworkTab, 'numeric');
            app.MicroeNodeBmaxTXpowerWEditField.Limits = [1 100];
            app.MicroeNodeBmaxTXpowerWEditField.ValueDisplayFormat = '%.2f';
            app.MicroeNodeBmaxTXpowerWEditField.ValueChangedFcn = createCallbackFcn(app, @MicroeNodeBmaxTXpowerWEditFieldValueChanged, true);
            app.MicroeNodeBmaxTXpowerWEditField.Value = 6.3;
            app.DownlinkfrequencyMHzEditFieldLabel = uilabel(app.NetworkTab);
            app.DownlinkfrequencyMHzEditFieldLabel.Text = 'Downlink frequency (MHz)';
            app.DownlinkfrequencyMHzEditField = uieditfield(app.NetworkTab, 'numeric');
            app.DownlinkfrequencyMHzEditField.Limits = [600 3000];
            app.DownlinkfrequencyMHzEditField.ValueDisplayFormat = '%.2f';
            app.DownlinkfrequencyMHzEditField.ValueChangedFcn = createCallbackFcn(app, @DownlinkfrequencyMHzEditFieldValueChanged, true);
            app.DownlinkfrequencyMHzEditField.Value = 1842.5;
            app.UplinkfrequencyMHzEditFieldLabel = uilabel(app.NetworkTab);
            app.UplinkfrequencyMHzEditFieldLabel.Text = 'Uplink frequency (MHz)';
            app.UplinkfrequencyMHzEditField = uieditfield(app.NetworkTab, 'numeric');
            app.UplinkfrequencyMHzEditField.Limits = [600 3000];
            app.UplinkfrequencyMHzEditField.ValueDisplayFormat = '%.2f';
            app.UplinkfrequencyMHzEditField.ValueChangedFcn = createCallbackFcn(app, @UplinkfrequencyMHzEditFieldValueChanged, true);
            app.UplinkfrequencyMHzEditField.Value = 1747.5;
            app.ExportTab = uitab(app.TabGroup2);
            app.ExportTab.Title = 'Export';
            app.ExportconfigurationButton = uibutton(app.ExportTab, 'push');
            app.ExportconfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @ExportconfigurationButtonPushed, true);
            app.ExportconfigurationButton.Text = 'Export configuration';
            app.Label_5 = uilabel(app.ExportTab);
            app.Label_5.Text = 'Use this tab to export the current setup. This will generate a full configuration for MONSTeR with the parameters selected.';
            app.ExportLabel = uilabel(app.ExportTab);
            app.ExportLabel.Text = 'Export';
            app.Label_6 = uilabel(app.ExportTab);
            app.Label_6.Text = 'The result is saved as a .mat file that can then be loaded as a variable in Matlab';
            app.RunTab = uitab(app.TabGroup);
            app.RunTab.Title = 'Run';
            app.Label_2 = uilabel(app.RunTab);
            app.Label_2.Text = 'Use this tab to run a simulation, first load the selected setup and then hit the run button.';
            app.RunasimulationLabel = uilabel(app.RunTab);
            app.RunasimulationLabel.Text = 'Run a simulation';
            app.Label_3 = uilabel(app.RunTab);
            app.Label_3.Text = 'While the simulation is running, log message will be displayed.';
            app.LoadsetupButton_2 = uibutton(app.RunTab, 'push');
            app.LoadsetupButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadsetupButton_2Pushed, true);
            app.LoadsetupButton_2.Text = 'Load setup';
            app.LoadthesetuptorunButton = uibutton(app.RunTab, 'push');
            app.LoadthesetuptorunButton.ButtonPushedFcn = createCallbackFcn(app, @LoadthesetuptorunButtonPushed, true);
            app.LoadthesetuptorunButton.Enable = 'off';
            app.LoadthesetuptorunButton.Text = 'Load the setup to run ';
            app.RunsimulationtosaveresultsButton = uibutton(app.RunTab, 'push');
            app.RunsimulationtosaveresultsButton.ButtonPushedFcn = createCallbackFcn(app, @RunsimulationtosaveresultsButtonPushed, true);
            app.RunsimulationtosaveresultsButton.Enable = 'off';
            app.RunsimulationtosaveresultsButton.Text = 'Run simulation to save results';
            app.SimulationexecutionlogListBoxLabel = uilabel(app.RunTab);
            app.SimulationexecutionlogListBoxLabel.Text = 'Simulation execution log';
            app.SimulationexecutionlogListBox = uilistbox(app.RunTab);
            app.SimulationexecutionlogListBox.Items = {};
            app.SimulationexecutionlogListBox.Value = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = monster

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MONSTeRUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MONSTeRUIFigure)
        end
    end
end